classdef BrainTumorDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        ConfidenceLabel        matlab.ui.control.Label
        PredictionLabel_2      matlab.ui.control.Label
        ImageLabel             matlab.ui.control.Label
        DetectButton           matlab.ui.control.Button
        LoadCustomImageButton  matlab.ui.control.Button
        ImageAxes_2            matlab.ui.control.UIAxes
        ImageAxes              matlab.ui.control.UIAxes
    end

    properties (Access = private)
        yoloModel      % Trained YOLO detector
        LastImageFile  % Path of last loaded image
        AppFolder      % Folder containing this .mlapp
        Property4 % Description
        medsam         % Medical Segment Anything model
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AppFolder = fileparts(which(mfilename));
            data = load(fullfile(app.AppFolder, 'tumorYolo2Detector.mat'));
            app.yoloModel = data.detector;
        
             % Load MedicalSAM model
            app.medsam = medicalSegmentAnythingModel;

            % Init GUI
            cla(app.ImageAxes); axis(app.ImageAxes, 'off');
            text(app.ImageAxes, 0.5, 0.5, 'Load an image to begin', ...
            'Units','normalized', 'HorizontalAlignment','center', ...
            'FontSize',14, 'Color',[.6 .6 .6]);

            cla(app.ImageAxes_2); axis(app.ImageAxes_2, 'off');
            app.ImageAxes.Visible = 'off';
            app.ImageAxes_2.Visible = 'off';
            app.ConfidenceLabel.Text = 'Confidence:';
        end

        % Button pushed function: LoadCustomImageButton
        function LoadCustomImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.tif;*.bmp','Image Files'}, ...
                                  'Select an Image', app.AppFolder);
            if isequal(path, 0), return; end
            fname = fullfile(path, file);
            app.LastImageFile = fname;
            im = imread(fname);
            cla(app.ImageAxes); imshow(im, 'Parent', app.ImageAxes);
            app.ImageAxes.Visible = 'on';
            cla(app.ImageAxes_2); app.ImageAxes_2.Visible = 'off';
            app.ConfidenceLabel.Text = 'Confidence:';
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            if isempty(app.LastImageFile)
                uialert(app.UIFigure, 'First load an image.', 'No Image');
                return;
            end
            I = imread(app.LastImageFile);
            if size(I, 3) == 1
                I = repmat(I, [1 1 3]);
            end
            originalSize = size(I);
            samInputSize = [256 256];

            [boxes, scores] = detect(app.yoloModel, I, 'Threshold', 0.5);

            if isempty(boxes)
                cla(app.ImageAxes_2);
                imshow(I, 'Parent', app.ImageAxes_2);
                app.ImageAxes_2.Visible = 'on';
                app.ConfidenceLabel.Text = 'Confidence: 0.00%';
                return;
             end

            % Extract embeddings
            I_resized = imresize(I, samInputSize);
            embeddings = extractEmbeddings(app.medsam, I_resized);

            % Plot detections
            cla(app.ImageAxes_2);
            imshow(I, 'Parent', app.ImageAxes_2);
            hold(app.ImageAxes_2, 'on');
            for j = 1:size(boxes, 1)
                box = boxes(j, :);
                scaleX = samInputSize(2) / originalSize(2);
                scaleY = samInputSize(1) / originalSize(1);
                boxPrompt = [box(1)*scaleX, box(2)*scaleY, box(3)*scaleX, box(4)*scaleY];

                mask = segmentObjectsFromEmbeddings(app.medsam, embeddings, ...
                        samInputSize, BoundingBox=boxPrompt);
                mask = imresize(mask, originalSize(1:2));

                rectangle(app.ImageAxes_2, 'Position', box, 'EdgeColor', 'g', 'LineWidth', 2);
                visboundaries(app.ImageAxes_2, mask, 'Color', 'r', 'LineWidth', 1);
                text(app.ImageAxes_2, box(1), box(2)-10, ...
                    sprintf('Tumour %.1f%%', scores(j)*100), ...
                    'Color', 'cyan', 'FontSize', 12, 'FontWeight', 'bold');
            end
            hold(app.ImageAxes_2, 'off');
            app.ImageAxes_2.Visible = 'on';

            % Confidence label
            app.ConfidenceLabel.Text = sprintf('Confidence: %.2f%%', max(scores)*100);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.ImageAxes_2 = uiaxes(app.UIFigure);
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.XTickLabel = {'[ ]'};
            app.ImageAxes_2.YTick = [];
            app.LoadCustomImageButton = uibutton(app.UIFigure, 'push');
            app.LoadCustomImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCustomImageButtonPushed, true);
            app.LoadCustomImageButton.Text = 'Load Custom Image';
            app.DetectButton = uibutton(app.UIFigure, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image';
            app.PredictionLabel_2 = uilabel(app.UIFigure);
            app.PredictionLabel_2.Text = 'Prediction';
            app.Panel = uipanel(app.UIFigure);
            app.ConfidenceLabel = uilabel(app.Panel);
            app.ConfidenceLabel.Text = 'Confidence:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BrainTumorDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end